classdef FieldImageColorCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        TextArea                   matlab.ui.control.TextArea
        RestAllButton              matlab.ui.control.Button
        SaveResultsButton          matlab.ui.control.Button
        CalculateStatisticsButton  matlab.ui.control.Button
        DrawROIButton              matlab.ui.control.Button
        DrawScaleBarButton_2       matlab.ui.control.Button
        CalibrateColorsButton      matlab.ui.control.Button
        SelectImageButton          matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.dng;*.cr2;*.CR2', 'RAW Images (*.dng, *.cr2)'}, ...
                'Select DNG or CR2 Image');

            if isequal(file, 0)
                return; % User canceled
            end

            app.dngFilePath = fullfile(path, file);
            app.CalibrateButton.Enable = 'on';
            app.TextArea.Value = sprintf('Image selected: %s', file);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Panel = uipanel(app.UIFigure);
            app.SelectImageButton = uibutton(app.Panel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.CalibrateColorsButton = uibutton(app.Panel, 'push');
            app.CalibrateColorsButton.Text = 'Calibrate Colors';
            app.DrawScaleBarButton_2 = uibutton(app.Panel, 'push');
            app.DrawScaleBarButton_2.Text = 'Draw Scale Bar';
            app.DrawROIButton = uibutton(app.Panel, 'push');
            app.DrawROIButton.Text = 'Draw ROI';
            app.CalculateStatisticsButton = uibutton(app.Panel, 'push');
            app.CalculateStatisticsButton.Text = 'Calculate Statistics';
            app.SaveResultsButton = uibutton(app.Panel, 'push');
            app.SaveResultsButton.Text = 'Save Results';
            app.RestAllButton = uibutton(app.Panel, 'push');
            app.RestAllButton.Text = 'Rest All';
            app.TextArea = uitextarea(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FieldImageColorCalibration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end